classdef app_ver_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        CalligraphydrawingrobotLabel  matlab.ui.control.Label
        ImageAxes                     matlab.ui.control.UIAxes
        StreamAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        SelectedImage
        SelectedImageFile             string
        DetechImg
        StopFlag                      logical = false
        DropDown                      matlab.ui.control.DropDown % Thêm DropDown nếu nó được sử dụng
    end

    methods (Access = private)
        
        function updateimage(app, imagefile)
            % For corn.tif, read the second image in the file 
            if strcmp(imagefile, 'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Display the image
            imagesc(app.ImageAxes, im);
            app.ImageAxes.Visible = 'on';
            
            % Store the selected image file
            app.SelectedImageFile = imagefile;
        end
     
        function startImageStream(app)
            % Connect to CoppeliaSim
            sim = remApi('remoteApi');
            sim.simxFinish(-1); % Close all previous connections
            clientID = sim.simxStart('127.0.0.1', 19999, true, true, 5000, 5); % Connect to CoppeliaSim server
            if (clientID > -1)
                disp('Connected to CoppeliaSim API server');
                
                % Get handle of the camera from CoppeliaSim
                [~, camHandle] = sim.simxGetObjectHandle(clientID, 'Vision_sensor', sim.simx_opmode_blocking);
                
                % Start streaming image data from the camera
                [~, resolution, ~] = sim.simxGetVisionSensorImage(clientID, camHandle, 0, sim.simx_opmode_streaming);
                
                % Loop to continuously receive and display image data
                while ~app.StopFlag
                    % Get image data from the camera
                    [~, resolution, image] = sim.simxGetVisionSensorImage(clientID, camHandle, 0, sim.simx_opmode_buffer);
                    
                    % Process and display the image data
                    if resolution(1) > 0 && resolution(2) > 0 % Check if valid image data received
                        img = reshape(image, [resolution(1), resolution(2), 3]);
                        % Display the image data on StreamAxes
                        imagesc(app.StreamAxes, img);
                        app.StreamAxes.Visible = 'on';
                        drawnow;
                    else
                        disp('Error receiving image data from camera.');
                    end
                end
            else
                disp('Failed to connect to CoppeliaSim API server.');
            end
            
            % Clean up connection to CoppeliaSim
            sim.simxFinish(-1);
            sim.delete();
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'on';
            axis(app.ImageAxes, 'image');
            axis(app.StreamAxes, 'image');
        end

        % Callback function
        function DropDownValueChanged(app, event)
           % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp','Các tệp ảnh (*.jpg, *.png, *.bmp)'},'Chọn hình ảnh mới');
            if isequal(fileName, 0)
                disp('Không có hình ảnh nào được chọn.');
                return;
            else
                disp(['Hình ảnh được chọn: ', fullfile(filePath, fileName)]);
                app.SelectedImage = imread(fullfile(filePath, fileName)); % Đọc và lưu hình ảnh đã chọn vào biến thuộc tính của app
                
                % Hiển thị hình ảnh lên ô nhỏ trên giao diện
                imageFile = fullfile(filePath, fileName);
                updateimage(app, imageFile);
                app.SelectedImageFile = imageFile;
            end            
        end

        % Callback function: RunButton, StreamAxes
        function RunButtonPushed(app, event)
            clearvars -except app; % Xóa tất cả các biến trong không gian làm việc
            f = parfeval(@startImageStream, 0, app);
            sim = remApi('remoteApi'); % Khởi tạo đối tượng API từ remoteApi
            sim.simxFinish(-1); % Đóng tất cả các kết nối trước đó
            clientID = sim.simxStart('127.0.0.1', 19999, true, true, 5000, 5); % Kết nối tới máy chủ CoppeliaSim tại địa chỉ và cổng xác định
            if (clientID > -1)
                disp('Đã kết nối tới máy chủ API từ xa');
                
                J = app.SelectedImage; % Sử dụng ảnh đã được lưu trong thuộc tính của đối tượng app
                
                % Các bước xử lý ảnh và điều khiển robot như đã viết
                K = imresize(J, 0.25); % Thay đổi kích thước hình ảnh
                if size(K, 3) == 3
                    I = rgb2gray(K); % Chuyển đổi hình ảnh sang thang độ xám nếu hình ảnh là màu
                else
                    I = K;
                end
        
                sf = 3; % Hệ số phóng đại
                sz = size(I);
                xg = 1:sz(1);
                yg = 1:sz(2);
                F = griddedInterpolant({xg,yg},double(I)); % Nội suy hình ảnh
                F.Method = 'cubic';
                xq = (1:(1/sf)*(0.53):sz(1))'; % Tỷ lệ phóng đại lên theo hệ số phóng đại
                yq = (1:(1/sf)*(0.53):sz(2))';
                I_re = im2bw(uint8(F({xq,yq})),0.5); % Chuyển đổi hình ảnh sang nhị phân
                I_c = I_re; % Để kiểm soát vùng thay đổi làm mịn
                
                % Thuật toán lọc lặp lại bên dưới để áp dụng cho ứng dụng này
                sz = size(I_re);
                L1 = bwlabel(I_re);
                N = max(L1,[],'all');
                windowSize = 19; % Lọc, làm mờ nhẹ rồi ngưỡng lại để loại bỏ các cạnh
                for k = 1:10 % lặp lại k lần
                    L1 = bwlabel(I_re);
                    I_b= zeros(sz);
                    % Lấy từng đặc trưng và lưu vào mảng tạm thời
                    for i = 1:N
                        I_temp = zeros(sz);
                        I_b1 = I_temp;
                        for j = 1:sz(1)
                            for jj = 1:sz(2)
                                if L1(j,jj) == i
                                    I_temp(j,jj) = I_re(j,jj);
                                end
                            end
                        end
                        % Điều chỉnh kích thước cửa sổ cho các hạt nhỏ
                        if sum(I_temp(:) > 0) < 1*(windowSize^2)*pi*sf^2 
                            window = round(windowSize/3);
                        else
                            window = windowSize;
                        end
                        kernel = ones(window)/window^2;
                        I_b1 = conv2(I_temp, kernel, 'same'); % Lọc bằng phép tích chập 2D
                        % Ngăn chặn hợp nhất các hạt hoặc tới cạnh bằng cách thêm một không
                        for j = 1:sz(1)
                            for jj = 1:sz(2)
                                if I_b(j,jj) == 0 && I_b1(j,jj) > 0
                                    I_b(j,jj) = I_b1(j,jj);
                                elseif I_b(j,jj) ~= 0 && I_b1(j,jj) ~= 0
                                    I_b(j,jj) = 0; 
                                elseif j == sz(1) || jj == sz(2)
                                    I_b(j,jj) = 0; 
                                end
                            end
                        end
                    end
                    I_re = I_b > 0.5;
                end
                I_2 = I_re;
                % Loại bỏ các pixel nhô ra
                I_2 = ~I_2;
                I_2 = bwmorph(I_2,'majority');
                I_2 = ~I_2;
                I_2 = bwmorph(I_2,'majority');
                [B, L] = bwboundaries(I_2);
                imagesc(app.StreamAxes, I_2); % Hiển thị hình ảnh đã lọc trên StreamAxes
                hold(app.StreamAxes, 'on');
                
                % Duyệt qua từng biên
                for k = 1:length(B)
                    boundary = B{k};
                    plot(app.StreamAxes, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2); % Vẽ đường viền trên StreamAxes
                end
                
                % Phần này của mã tạo ra đường đi của cánh tay robot
                x= [];
                y= [];
                z= [];
                count =0;
                
                for k = 1:length(B)
                    boundary = B{k};
                    for i=1:length(boundary(:,2))
                        count = count+1;
                        x(count) = boundary(i,2);
                        y(count) = boundary(i,1);
                        z(count) = 0;
                    end
                    count = count -1;
                    z(count)= 30; % Điều chỉnh trục Z cho mỗi biên
                end
                [~,dum]= sim.simxGetObjectHandle(clientID,'IRB140_target',sim.simx_opmode_blocking); % Lấy handle của đối tượng mục tiêu IRB140
        
                for m= 1:length(x)
                    [returnCode]=sim.simxSetObjectPosition(clientID,dum,-1,[-0.22+(x(m)*0.0008),-0.1+(y(m)*0.0008),(z(m)*0.004)+0.515],sim.simx_opmode_blocking); % Thiết lập vị trí của đối tượng
                end
                [returnCode]=sim.simxSetObjectPosition(clientID,dum,-1,[-0.4,-0.45,0.625],sim.simx_opmode_blocking); % Di chuyển cánh tay robot ra khỏi khu vực vẽ
            
                % Lấy handle của camera
                [~, camHandle] = sim.simxGetObjectHandle(clientID,'Vision_sensor',sim.simx_opmode_blocking);
            
                % Lấy dữ liệu hình ảnh từ camera
                [returnCode,resolution,image]=sim.simxGetVisionSensorImage(clientID,camHandle,0,sim.simx_opmode_streaming);
            
                % Hiển thị hình ảnh từ camera
                if returnCode == sim.simx_return_ok
                    img = reshape(image,[resolution(1),resolution(2),3]);
                    imagesc(app.StreamAxes, img); % Hiển thị hình ảnh từ camera trên StreamAxes
                else
                    disp('Lỗi khi lấy hình ảnh từ camera.');
                end
%                 app.DetechImg = reshape(image,[resolution(1),resolution(2),3]);
%                 imagesc(app.StreamAxes, app.SelectedImage); % Hiển thị ảnh đã chọn trên StreamAxes
        
            end
            sim.simxFinish(-1); % Đóng kết nối với CoppeliaSim
            sim.delete(); % Xóa đối tượng API
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.StopFlag = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Chọn chữ muốn viết';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CalligraphydrawingrobotLabel = uilabel(app.UIFigure);
            app.CalligraphydrawingrobotLabel.Text = 'Calligraphy drawing robot';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.StreamAxes = uiaxes(app.UIFigure);
            app.StreamAxes.XTick = [];
            app.StreamAxes.XTickLabel = {'[ ]'};
            app.StreamAxes.YTick = [];
            app.StreamAxes.ButtonDownFcn = createCallbackFcn(app, @RunButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ver_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end